classdef cuedConditioningGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        qCondUIFigure         matlab.ui.Figure
        UITable               matlab.ui.control.Table
        Panel                 matlab.ui.container.Panel
        TrialButton           matlab.ui.control.Button
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        RewardButton          matlab.ui.control.Button
        Panel_2               matlab.ui.container.Panel
        USBPortDropDownLabel  matlab.ui.control.Label
        USBPortDropDown       matlab.ui.control.DropDown
        ConnectButton         matlab.ui.control.StateButton
        CurrentFileLabel      matlab.ui.control.Label
        curFile               matlab.ui.control.Label
        browseButton          matlab.ui.control.Button
        NewfileButton         matlab.ui.control.Button
        DeletefileButton      matlab.ui.control.Button
        RecButton             matlab.ui.control.Button
        StopExpButton         matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        CurrentEventLabel     matlab.ui.control.Label
        noneLabel_2           matlab.ui.control.Label
        Panel_3               matlab.ui.container.Panel
    end

    
    properties (Access = private)
        serialObj            % Object storing the serial communication with arduino
        defPath = [];
        currFile
    end
    
    methods (Access = private)
        
        function results = func(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Detect COM ports and fills the drop down menu
            ports = serialportlist("available");
            if isempty(ports)
                app.USBPortDropDown.Items = "None";
                app.USBPortDropDown.Value = "None";
            else
                app.USBPortDropDown.Items = ports;
                app.USBPortDropDown.Value = ports(end);
            end
        
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value
                selectedPort = app.USBPortDropDown.Value;
                app.serialObj = serialport(selectedPort,9600,'Timeout',2);
                app.ConnectButton.Text = 'Connecting...';
                app.ConnectButton.BackgroundColor = [.93 .69 .13];
                
                writeline(app.serialObj,'handshakeMAT')
                warning('off','serialport:serialport:ReadlineWarning')
                msg = readline(app.serialObj);
                if strcmpi(msg, 'handshakeARD')
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.BackgroundColor = [.39 .83 .07];
                else
                    message = 'Selected COM port was not responsive. Not an arduino or wrong firmware on the arduino';
                    uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Text = 'Connect';
                    app.ConnectButton.BackgroundColor = [.96 .96 .96];
                end
                
            else
                delete(app.serialObj);
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [.96 .96 .96];
                
            end
            warning('on','serialport:serialport:ReadlineWarning')
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            tit = 'Select a file to append data';
            if isempty(app.defPath)
                filter = '*.txt';
            else
                filter = [app.defPath '*.txt'];
            end
            [f, p] = uigetfile(filter,tit);
            
            if ~isequal(f,0)
                app.defPath = p;
                app.currFile = f;
                app.curFile.Text = f;
            end
        end

        % Button pushed function: NewfileButton
        function NewfileButtonPushed(app, event)
            tit = 'Create a new recording file';
            defName = ['qCond_' datestr(now,'YYYYmmDD-hhMMss') '.txt'];
            if ~isempty(app.defPath)
                defName = [app.defPath defName];
            end
            
            [f, p] = uiputfile('*.txt',tit,defName);
            
            if ~isequal(f,0)         
                fID = fopen([p f], 'wt+', 'n', 'UTF-8');
                fclose(fID);
                app.defPath = p;
                app.currFile = f;
                app.curFile.Text = f;
            end
        end

        % Button pushed function: DeletefileButton
        function DeletefileButtonPushed(app, event)
            fN = [app.defPath app.currFile];
            if exist(fN, 'file')
                delete(fN)
                app.currFile = [];
                app.curFile.Text = 'none';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.qCondUIFigure = uifigure('Visible', 'off');
            app.qCondUIFigure.Name = 'qCond';
            app.UITable = uitable(app.qCondUIFigure);
            app.UITable.ColumnName = {'Timestamp'; 'Event'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.qCondUIFigure);
            app.Panel.Title = 'Panel';
            app.TrialButton = uibutton(app.Panel, 'push');
            app.TrialButton.Text = 'Trial';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.Text = '<<';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.Text = '>>';
            app.RewardButton = uibutton(app.Panel, 'push');
            app.RewardButton.Text = 'Reward';
            app.Panel_2 = uipanel(app.qCondUIFigure);
            app.USBPortDropDownLabel = uilabel(app.Panel_2);
            app.USBPortDropDownLabel.Text = 'USB Port';
            app.USBPortDropDown = uidropdown(app.Panel_2);
            app.ConnectButton = uibutton(app.Panel_2, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.Panel_3 = uipanel(app.qCondUIFigure);
            app.CurrentFileLabel = uilabel(app.Panel_3);
            app.CurrentFileLabel.Text = 'Current File:';
            app.curFile = uilabel(app.Panel_3);
            app.curFile.Text = 'none';
            app.browseButton = uibutton(app.Panel_3, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.NewfileButton = uibutton(app.Panel_3, 'push');
            app.NewfileButton.ButtonPushedFcn = createCallbackFcn(app, @NewfileButtonPushed, true);
            app.NewfileButton.Text = 'New file';
            app.DeletefileButton = uibutton(app.Panel_3, 'push');
            app.DeletefileButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefileButtonPushed, true);
            app.DeletefileButton.Text = 'Delete file';
            app.RecButton = uibutton(app.qCondUIFigure, 'push');
            app.RecButton.Text = 'Rec';
            app.StopExpButton = uibutton(app.qCondUIFigure, 'push');
            app.StopExpButton.Text = 'Stop Exp';
            app.Button_3 = uibutton(app.qCondUIFigure, 'push');
            app.CurrentEventLabel = uilabel(app.qCondUIFigure);
            app.CurrentEventLabel.Text = 'Current Event:';
            app.noneLabel_2 = uilabel(app.qCondUIFigure);
            app.noneLabel_2.Text = 'none';
            % Show the figure after all components are created
            app.qCondUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cuedConditioningGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.qCondUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.qCondUIFigure)
        end
    end
end